classdef lidar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        write_file     matlab.ui.control.Button
        get_obj        matlab.ui.control.Button
        obj_fileField  matlab.ui.control.EditField
        EditField      matlab.ui.control.EditField
        display        matlab.ui.control.Button
        getpos         matlab.ui.control.Button
        UIAxes         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        obj_file = '';
        file = '';
        T = 0;
%         wm;
        mwLon = 0;
        mwLat = 0;
        mwspd = 0;
        lat_T= 0;
        lon_T = 0;
        Time = 0;
        Name='';
        varTypes = {'string', 'datetime', 'double','double','double', 'string'};
        varNames = {'Name', 'Time', 'latitude', 'longtitude', 'speed', 'file'};
        sz = [1 6];
    end
    
    methods (Access = private)
        
        function readObj(app, fname)
            % set up field types
            v = []; vt = []; vn = []; f.v = []; f.vt = []; f.vn = [];
            
            
            if(exist('fname','var')==0)
                [filename, filefolder] = uigetfile('*.obj', 'Read obj-file');
                fname = [filefolder filename];
            end
            fid = fopen(fname);
            
            % parse .obj file 
            while 1    
                tline = fgetl(fid);
                if ~ischar(tline),   break,   end  % exit at end of file 
                 ln = sscanf(tline,'%s',1); % line type 
                 %disp(ln)
                switch ln
                    case 'v'   % mesh vertexs
                        v = [v; sscanf(tline(2:end),'%f')'];
                    case 'vt'  % texture coordinate
                        vt = [vt; sscanf(tline(3:end),'%f')'];
                    case 'vn'  % normal coordinate
                        vn = [vn; sscanf(tline(3:end),'%f')'];
                    case 'f'   % face definition
                        fv = []; fvt = []; fvn = [];
                        str = textscan(tline(2:end),'%s'); str = str{1};
                   
                       nf = length(findstr(str{1},'/')); % number of fields with this face vertices
            
            
                       [tok str] = strtok(str,'//');     % vertex only
                        for k = 1:length(tok) fv = [fv str2num(tok{k})]; end
                       
                        if (nf > 0) 
                        [tok str] = strtok(str,'//');   % add texture coordinates
                            for k = 1:length(tok) fvt = [fvt str2num(tok{k})]; end
                        end
                        if (nf > 1) 
                        [tok str] = strtok(str,'//');   % add normal coordinates
                            for k = 1:length(tok) fvn = [fvn str2num(tok{k})]; end
                        end
                         f.v = [f.v; fv]; f.vt = [f.vt; fvt]; f.vn = [f.vn; fvn];
                end
            end
            fclose(fid);
            
            % set up matlab object 
            obj.v = v; obj.vt = vt; obj.vn = vn; obj.f = f;
            end
        
        function display_obj(app, obj,texture)
 
        
            if(exist('texture','var')==0)
                [filename, filefolder] = uigetfile('*.jpg', 'All files');
                texture = [filefolder filename];
            end
            
            texture = imread(texture);
            texture_img = flipdim(texture,1);
            [sy sx sz] = size(texture_img);
            texture_img =  reshape(texture_img,sy*sx,sz);
            
            % make image 3D if grayscale
            if app.sz == 1
                texture_img = repmat(texture_img,1,3);
            end
            
            % select what texture correspond to each vertex according to face
            % definition
            [vertex_idx fv_idx] = unique(obj.f.v);
            texture_idx = obj.f.vt(fv_idx);
            
            x = abs(round(obj.vt(:,1)*(sx-1)))+1;
            y = abs(round(obj.vt(:,2)*(sy-1)))+1;
            xy = sub2ind([sy sx],y,x);
            texture_pts = xy(texture_idx);
            tval = double(texture_img(texture_pts,:))/255;


            % display object
            figure; patch('vertices',obj.v,'faces',obj.f.v,'FaceVertexCData', tval);
            shading interp
            colormap gray(256);
            lighting phong;
            camproj('perspective');
            axis square; 
            axis off;
            axis equal
            axis tight;
            cameramenu
                        
            end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Make　table when app start
            if (exist('object.csv', 'file')==0)
                 app.varTypes = {'string', 'datetime', 'double','double','double', 'string'};
                 app.varNames = {'Name', 'Time', 'latitude', 'longtitude', 'speed', 'file'};
                 app.sz = [1 6];
%                  app.T = table('Size',app.sz,'VariableTypes',app.varTypes,'VariableNames',app.varNames);
                 disp('No csv file');
             else 
                 app.T = readtable('object.csv');
%                  disp(app.T);
                 disp('Yes');
            end
            disp(app.T);
        end

        % Button pushed function: display
        function displayPushed(app, event)
            filename = app.EditField.Value;
            load(filename);


            p = Position;
            lat = p.latitude;
            lon = p.longitude;
            spd = p.speed;

            nBins = 10;
            binSpacing = (max(spd) - min(spd))/nBins; 
            binRanges = min(spd):binSpacing:max(spd)-binSpacing; 
            
            % Add an inf to binRanges to enclose the values above the last bin.
            binRanges(end+1) = inf;
            
            % |histc| determines which bin each speed value falls into.
            [~, spdBins] = histc(spd, binRanges);
            
            lat = lat';
            lon = lon';
            spdBins = spdBins';
             
            s = geoshape();

            for k = 1:nBins
                
                % Keep only the lat/lon values which match the current bin. Leave the 
                % rest as NaN, which are interpreted as breaks in the line segments.
                latValid = nan(1, length(lat));
                latValid(spdBins==k) = lat(spdBins==k);
                
                lonValid = nan(1, length(lon));
                lonValid(spdBins==k) = lon(spdBins==k);    
            
                % To make the path continuous despite being segmented into different
                % colors, the lat/lon values that occur after transitioning from the
                % current speed bin to another speed bin will need to be kept.
                transitions = [diff(spdBins) 0];
                insertionInd = find(spdBins==k & transitions~=0) + 1;
            
                % Preallocate space for and insert the extra lat/lon values.
                latSeg = zeros(1, length(latValid) + length(insertionInd));
                latSeg(insertionInd + (0:length(insertionInd)-1)) = lat(insertionInd);
                latSeg(~latSeg) = latValid;
                
                lonSeg = zeros(1, length(lonValid) + length(insertionInd));
                lonSeg(insertionInd + (0:length(insertionInd)-1)) = lon(insertionInd);
                lonSeg(~lonSeg) = lonValid;
            
                % Add the lat/lon segments to the geographic shape vector.
                s(k) = geoshape(latSeg, lonSeg);
                
            end
               
            wm = webmap('Open Street Map');
            app.mwLat = mean(lat);
            app.mwLon = mean(lon);
            app.mwspd = mean(spd);
            app.Time = median(p.Timestamp);


            colors = autumn(nBins);
%             wmline(s, 'Color', colors, 'Width', 5);
            
            wmmarker(wm, app.mwLat, app.mwLon, 'Color','green', ...
                'FeatureName',app.obj_fileField.Value);
           
            app.lat_T = app.T.latitude;
            disp(app.lat_T);
            app.lon_T = app.T.longtitude;
            disp(app.lon_T);

            wmmarker(wm, app.lat_T, app.lon_T);
            wmcenter(app.mwLat, app.mwLon);
            wmzoom(16);
 
            
            disp(app.T);


%             if app.mp4Button.Value == true
            v = VideoReader(app.obj_file);
            currAxes = app.UIAxes;
            while hasFrame(v)
                vidFrame = readFrame(v);
                image(vidFrame, 'Parent', currAxes);
                currAxes.Visible = 'off';
                pause(1/v.FrameRate);
            end   
            
%             else
%                 obj = readObj(app, app.obj_file);
%                 currAxes = app.UIAxes;
%                 currAxes.Visible = 'off';
%                 [texture, path] = uigetfile('*.jpg', 'All files');
%                 currAxes = display_obj(app, obj, texture);
%             end

        end

        % Button pushed function: getpos
        function getposPushed(app, event)
            [app.file, path] = uigetfile();
            if isequal(app.file, 0)
                disp('User selected cancel')
            else
                disp(['User selected', fullfile(path, app.file)])
                app.EditField.Value = app.file;
            end

        end

        % Button pushed function: get_obj
        function get_objPushed(app, event)
            [app.obj_file, path] = uigetfile('*', 'All files');
            if isequal(app.obj_file, 0)
                disp('User selected cancel')
            else
                disp(['User selected', fullfile(path, app.obj_file)])
                app.obj_fileField.Value = app.obj_file;
            end
        end

        % Button pushed function: write_file
        function write_filePushed(app, event)

            prompt = {'オブジェクト名の入力:'};
            dlgtitle = 'Input';
            dims = [1 35];
            answer = inputdlg(prompt,dlgtitle,dims);
            app.Name = answer;
            if isequal(app.Name, 0)
                disp('No Name');
            else
                add_data = {app.Name, app.Time, app.mwLat,app.mwLon, app.mwspd, app.obj_fileField.Value};
                app.T = [app.T;add_data];
                writetable(app.T, 'object.csv');
            end
            
        end

        % Callback function
        function makeTablePushed(app, event)
%             varTypes = {'string', 'datetime', 'double','double','double', 'string'};
%             varNames = {'Name', 'Time', 'latitude', 'longtitude', 'speed', 'file'};
%             sz = [1 6];
%             app.T = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'3D-object'; ''})
            app.getpos = uibutton(app.UIFigure, 'push');
            app.getpos.ButtonPushedFcn = createCallbackFcn(app, @getposPushed, true);
            app.getpos.Text = '位置情報取得';
            app.display = uibutton(app.UIFigure, 'push');
            app.display.ButtonPushedFcn = createCallbackFcn(app, @displayPushed, true);
            app.display.Text = '表示';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.obj_fileField = uieditfield(app.UIFigure, 'text');
            app.get_obj = uibutton(app.UIFigure, 'push');
            app.get_obj.ButtonPushedFcn = createCallbackFcn(app, @get_objPushed, true);
            app.get_obj.Text = 'オブジェクト読込';
            app.write_file = uibutton(app.UIFigure, 'push');
            app.write_file.ButtonPushedFcn = createCallbackFcn(app, @write_filePushed, true);
            app.write_file.Text = 'ファイル書出';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lidar

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end